classdef MewRecorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Mn_Setting              matlab.ui.container.Menu
        Mn_SetMaxTimeOut        matlab.ui.container.Menu
        Mn_SelectAudioDevice    matlab.ui.container.Menu
        Mn_AudioSettings        matlab.ui.container.Menu
        Mn_Info                 matlab.ui.container.Menu
        Mn_AudioInfo            matlab.ui.container.Menu
        Mn_About                matlab.ui.container.Menu
        Mn_EchoWaveTools        matlab.ui.container.Menu
        Mn_ConvertTVDtoMP4      matlab.ui.container.Menu
        LoadEchoWavePresetMenu  matlab.ui.container.Menu
        Bn_Record               matlab.ui.control.StateButton
        Lb_AudioSettings        matlab.ui.control.Label
        Ck_ifRecordTelemed      matlab.ui.control.CheckBox
        Ed_RecordDur            matlab.ui.control.NumericEditField
        Ck_RecordByDur          matlab.ui.control.CheckBox
        Lb_MaxTimeOut           matlab.ui.control.Label
        OutputfolderLabel       matlab.ui.control.Label
        Bn_SelectOutputFolder   matlab.ui.control.Button
        Ck_ifNormAudio          matlab.ui.control.CheckBox
        Ed_TimeRecorded         matlab.ui.control.EditField
        TimeRecordedLabel       matlab.ui.control.Label
        Pn_MsgBox               matlab.ui.container.Panel
        Msg1                    matlab.ui.control.HTML
        Bn_Stop                 matlab.ui.control.Button
        Pn_Filename             matlab.ui.container.Panel
        Ed_Filename             matlab.ui.control.EditField
        FilenameLabel           matlab.ui.control.Label
        Ck_AppendTime           matlab.ui.control.CheckBox
        Ck_AppendDate           matlab.ui.control.CheckBox
        Ed_FilePrefix           matlab.ui.control.EditField
        FilenameprefixLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppName = 'MewRecorder';
        Version = '1.0.2'; Update = '03JUL2023';  % Update version
        minMatlabVer = '9.13'; % R2022b
        minMatlabVerName = 'R2022b'; 
        logFilename = '_MewRecorder.log';
        status = 'inactive'; % status: inactive, ready, busy
        flag_busy = false;
        maxTimeOut = 7200; % max time out in seconds. 
        isRecording = false; % flag for recoding status
        OutputPath = pwd;
        appStartUpTime = []; recordStartTime = []; recordStopTime = [];  recordStartTimeStr = ''; recordStopTimeStr = ''
        audioExt = '.wav'; 
        audio_dev_idx = 0; audio_dev_name ='';   % Default audio device = 0
        audio_srate = 44100;  num_audio_channels = 2; user_num_audio_channels = 2; audio_bit_rate = 16; 
        recordingTimer = []; timerPeriod = 0.050;      
        save_file_prefix = ''; FullPathFilename = ''; AudioFullPathFilename = ''; TvdFullPathFilename = ''; 
        audioRecObj = []; asmcmd = []; EchoWavePath = []; EchoWaveCmd = [];
        dateFormat = 'yyyy-MM-dd'; timeFormat = 'HH-mm-ss-SSS';
        %% Telemed ID:
        id_scanning_state_set = 201; id_scanning_state_get = 200;
        id_state_b_b1r = 1; % B-mode scan running
        id_state_b_b1f = 2;  % B-mode frozen
    end
    
    methods (Access = private)
       %%
        function EchoWaveStartRecord(app)
            % freeze ultrasound scanning
            % if (app.asmcmd.IsRunState() == 1), app.asmcmd.FreezeRun(); end
            % % pause cine playback
            % if (app.asmcmd.IsPlayState() == 1), app.asmcmd.PlayPause(); end
            %Start recording:
           app.asmcmd.ParamSet(app.id_scanning_state_set, app.id_state_b_b1r); % B-mode scan running
        end %EchoWaveStartRecord(asmcmd)
        %%
        function EchoWaveStopAndSave(app)
            % id_scanning_state_set = 201; id_scanning_state_get = 200;
            % id_state_b_b1r = 1; % B-mode scan running
            % id_state_b_b1f = 2;  % B-mode frozen
            saveCineCmd = [app.EchoWaveCmd ' -save_cine tvd "' app.TvdFullPathFilename '"']; % must specify absolute path for the output  file.
            % stop recording
            % if (app.asmcmd.IsRecordingState() == 1), app.asmcmd.RecordStop(); end
            % freeze ultrasound scanning
            EchoWaveFreeze(app);
            % pause cine playback
            % if (app.asmcmd.IsPlayState() == 1), app.asmcmd.PlayPause(); end
            % save TVD file
            [status,cmdout] = system(saveCineCmd, '-echo');  % It roughly takes 1 s to save 5 s video; takes 5 s to save 5 min video.
            % tic
            % nFrames = app.asmcmd.GetFramesCount();
            % toc
        end %EchoWaveStopRecordAndSave()  
        
        function success = SaveAudio(app)
            success = false;
            if isrecording(app.audioRecObj), stop(app.audioRecObj);end
            s = getaudiodata(app.audioRecObj);
            nChannels = size(s, 2);
            if app.Ck_ifNormAudio.Value
                for i = 1:nChannels
                    s1 = s(:,i); s1 = s1 - mean(s1); 
                    s1 = s1 ./ max(abs(s1)); s(:,i) = s1;
                end
            end
            try
	            audiowrite(app.AudioFullPathFilename, s , app.audio_srate);
            catch
                success = false; delete(app.audioRecObj); return;
            end
            delete(app.audioRecObj);
            success = true;
        end %SaveAudio
        %%
        function appSetStatus(app, status)
            busy = false; active = false;
            %% Disable the following functions/buttons when busy (enable them when ready):
            funcsToBeDisabledIfBusy = ...
                {app.Bn_Record; app.Ed_FilePrefix; app.Mn_Setting; ...
                 app.Bn_SelectOutputFolder; app.Ed_RecordDur; app.Ck_RecordByDur; ...
                 app.Pn_Filename; app.Mn_Setting; app.Mn_Info; ...
                 app.Ck_ifNormAudio; app.Ck_ifRecordTelemed; app.Mn_EchoWaveTools
                };
            %%
            funcsToBeDisabledIfInactive = ...
                {app.Bn_Record; app.Bn_Stop; ...
                };
            %%
            if strcmpi(status, 'busy')
                active = true; busy = true; app.status = 'busy';               
            elseif strcmpi(status, 'ready')
                active = true; busy = false; app.status = 'ready';  
                app.Msg1.HTMLSource = '<p>Ready.</p>';
            elseif strcmpi(status, 'inactive')  
                active = false; busy = false; app.status = 'inactive';  
            end
            if busy
                app.flag_busy = true;
                for i = 1:numel(funcsToBeDisabledIfBusy), funcsToBeDisabledIfBusy{i}.Enable = 'off'; end
            else 
                app.flag_busy = false;
                for i = 1:numel(funcsToBeDisabledIfBusy), funcsToBeDisabledIfBusy{i}.Enable = 'on';end
                if ~app.Ck_ifRecordTelemed.Value
                    app.Mn_EchoWaveTools.Enable = 'off';
                end
                app.Bn_Record.Value = 0;
                updateFilenameTemplate(app);
            end 
            if ~active
                for i = 1:numel(funcsToBeDisabledIfInactive), funcsToBeDisabledIfInactive{i}.Enable = 'off'; end
            end 
        end %appSetStatus
        function yes = isActive(app)
          if strcmpi(app.status, 'inactive'), yes = false; else, yes = true; end
        end % isActive
        function yes = isBusy(app)
          if strcmpi(app.status, 'busy'), yes = true; app.flag_busy = true; else, yes = false; app.flag_busy = false; end
        end % isActive        
        %%
        function GenFilename(app)
            dateStr = string(app.recordStartTime, app.dateFormat);
            timeStr = string(app.recordStartTime, app.timeFormat);
            fn = app.save_file_prefix;
            if app.Ck_AppendDate.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), dateStr); end 
            if app.Ck_AppendTime.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), timeStr); end 
            app.Ed_Filename.Value = fn;
            app.FullPathFilename = fullfile(app.OutputPath, app.Ed_Filename.Value); 
            app.AudioFullPathFilename = sprintf('%s.%s', app.FullPathFilename, app.audioExt); 
            app.TvdFullPathFilename = sprintf('%s.%s', app.FullPathFilename, 'tvd'); 
            app.AudioFullPathFilename = app.NextAvailableFileName(app.AudioFullPathFilename);
            app.TvdFullPathFilename = app.NextAvailableFileName(app.TvdFullPathFilename);
            [~,fn,~]= fileparts(app.AudioFullPathFilename);
            app.Ed_Filename.Value = fn;
        end%GenFilename
        %% 
        function updateFilenameTemplate(app)
            prefix = app.Ed_FilePrefix.Value;
            exp = '[a-zA-Z_0-9-]+';
            prefix = regexp(prefix, exp, 'match');
            prefix = strjoin(prefix,'');
            app.save_file_prefix = strip(prefix, 'right', '_');
            if isempty(app.save_file_prefix), app.save_file_prefix= '_'; end
            fn = app.save_file_prefix;
            if app.Ck_AppendDate.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), app.dateFormat); end 
            if app.Ck_AppendTime.Value, fn = sprintf('%s_%s', strip(fn, 'right', '_'), app.timeFormat); end 
            app.Ed_Filename.Value = fn;          
        end %updateFilenameTemplate
        %%
        function do_log(app)
            app.recordStopTimeStr = string(app.recordStopTime, 'HH:mm:ss.SSS');
            app.recordStartTimeStr = string(app.recordStartTime, 'HH:mm:ss.SSS');
            logFullPathFN = fullfile(app.OutputPath, app.logFilename);
            fn = app.Ed_Filename.Value;
            try
                fid = fopen(logFullPathFN, 'at'); % append log file
                fprintf(fid,'%s; %s; %s\n', ...
                    fn, app.recordStartTimeStr, app.recordStopTimeStr);
                fclose(fid);
            catch
            end      
            app.Msg1.HTMLSource = sprintf('%s <p> %s <br>File saved. </p>', app.Msg1.HTMLSource, fn);
        end % do_log
        %% 
        function [success, msg] = DetectEchoWave(app)
            success = false; msg = '';
            if ~ispc, msg = 'Not PC!'; appSetStatus(app,'busy'); return; end
            if ~app.isAdminPC, msg  = 'Not Admin!'; appSetStatus(app,'busy'); return; end
            if isempty(app.EchoWavePath)
                app.EchoWavePath =  app.FindTelemedEchoWavePath; 
                if isempty(app.EchoWavePath)
                    msg = 'EchoWave path not found.'; appSetStatus(app,'busy'); return; 
                end
            end
            app.EchoWaveCmd = sprintf('"%s\\EchoWave.exe"', app.EchoWavePath);
            % [status, tasklist] = system('tasklist'); % this takes about 1 second.
            % if ~contains(tasklist, 'EchoWave'), [status, cmdout] = system(app.EchoWaveCmd); end
            asm_path =  [app.EchoWavePath '\Config\Plugins\AutoInt1Client.dll'];
            asm = NET.addAssembly(asm_path); %#ok<*NASGU> 
            app.asmcmd = AutoInt1Client.CmdInt1();
            if ~isEchoWaveConnected(app), msg = 'EchoWave not connected! (check:AutoInt1Client.dll)'; appSetStatus(app,'busy'); return; end
            success = true;
            EchoWaveFreeze(app); 
            app.num_audio_channels = 2; % Override 
            [support, msg]=CheckIfAudioDeviceSettingSupported(app);
            if ~support,  msg = 'Audio device not compatible with EchoWave!'; appSetStatus(app,'busy'); return; end
        end %DetectEchoWave
        %% 
        function EchoWaveFreeze(app)
            app.asmcmd.ParamSet(app.id_scanning_state_set, app.id_state_b_b1f);  % Freeze
        end %EchoWaveFreeze
        %%
        function out = isEchoWaveConnected(app)
            status = app.asmcmd.ConnectToRunningProgram(); 
            if (status ~= 0), out = false;  return; else, out = true; end
        end %isEchoWaveConnected
        %%
        function ConvertTVDtoMP4(app)
            outPath = ['"' app.OutputPath '"'];
            fromFormat = 'tvd'; toFormat = 'mp4';
            convertFmtCmd = [app.EchoWaveCmd ' -convert_directory ' outPath ' ' fromFormat ' ' toFormat]; 
            [status,cmdout] = system(convertFmtCmd, '-echo'); %#ok<*ASGLU>        
        end %ConvertTVDtoMP4   
        %%
        function [success, msg] = selectAudioDevice(app, devID)
            %% select audio device:            
            msg = ''; devs = audiodevinfo;  
            if isempty(devs) || isempty(devs.input),  success = false; app.audio_dev_idx =[]; msg = 'No audio device!'; return; end
            input_devs = devs.input; 
            dev_names = {input_devs.Name}'; device_IDs = cell2mat({input_devs.ID})';
            %% 
            if nargin < 2 || isempty(devID)
                listString = dev_names; prompt = {'Select audio device:'};
                [ind, ifSelected] = listdlg('PromptString',prompt,...
                    'SelectionMode','single','ListString', listString, ...
                    'ListSize', [480, 100], ...
                    'InitialValue', find(ismember(device_IDs, app.audio_dev_idx)));
                figure(app.UIFigure) % Get focus back on UIFigure
                if ~ifSelected, success = false; app.audio_dev_idx =[]; app.audio_dev_name =''; msg = '<p></p>'; return;  end
                devID = device_IDs(ind);
            end %isempyt(devID)
            ind = find(ismember(device_IDs, devID));
            if ~any(ismember(device_IDs, devID)), success = false; app.audio_dev_idx =[]; app.audio_dev_name =''; msg = 'Wrong audio device ID!'; return; end  
            app.audio_dev_idx = devID;   app.audio_dev_name = dev_names{ind};  
            success = true; 
            if ~success, appSetStatus(app, 'inactive');end
            figure(app.UIFigure) % Get focus back on UIFigure
        end  %selectAudioDevice
        function [support, msg] = CheckIfAudioDeviceSettingSupported(app)
            %% Check if audio settings are supported:
            msg = '';input_IO = 1;  
            if isempty(app.audio_dev_idx), support = false; msg = 'No audio device'; appSetStatus(app, 'inactive'); return; end
            support = audiodevinfo(input_IO, app.audio_dev_idx, app.audio_srate, app.audio_bit_rate, app.num_audio_channels);
            app.Lb_AudioSettings.Text = ...
                    sprintf('[%d kHz; %d channel(s)]', round(app.audio_srate/1000), app.num_audio_channels);
            if support
                
            else
                msg = 'Audio settings not supported! <br>  Check (sampling rate, nChannels...): <br> Setting->Audio settings';
                appSetStatus(app, 'inactive');
            end 
        end %CheckIfAudioDeviceSettingSupported
    end %methods (Access = private)
    %% Below are supporting functions
    %% Call by app.FUNCTIONNAME
    methods (Access = public, Static=true)
        function out = isAdminPC
            if ~ispc, out = false; return; end
            out = System.Security.Principal.WindowsPrincipal(System.Security.Principal.WindowsIdentity.GetCurrent()).IsInRole(System.Security.Principal.WindowsBuiltInRole.Administrator);
        end% isAdminPC
        %%
        function EchoWavePath  = FindTelemedEchoWavePath(varargin)
        % Find the path of installed Echo Wave II in Windows. 
        % Return empty if not found.
        % WR Chen 26OCT2022
        %     Update:  02JUN2023  
        %              Starting from EchoWave 4.2.0, the application
        %              folder changed to Program Files\Telemed\Echo Wave II Application\.
        %              If both older and newer version of EchoWave applications
        %              exist, this script will return the newer version.
        sep = filesep;
        programFilesFolders = {'Program Files', 'Program Files (x86)'};
        EchoWaveFolders = {'Telemed\Echo Wave II Application\EchoWave II', 'Telemed\Echo Wave II'}; nEchoWaveFolders = numel(EchoWaveFolders);
        exeFN = 'EchoWave.exe';
        startDrive = 'C'; endDrive = 'Z';
        found = 0; EchoWavePath = [];
        for i = double(startDrive):double(endDrive)
            drive = [char(i), ':' sep]; 
            for j = 1:numel(programFilesFolders)
                programFilesFolder = programFilesFolders{j};
                for k = 1:nEchoWaveFolders            
                    EchoWaveFolder = EchoWaveFolders{k};
                    pathCandidate = [drive, programFilesFolder, sep, EchoWaveFolder];
                    candEchoWaveExeFN = fullfile(pathCandidate, exeFN);
                    if exist(candEchoWaveExeFN, 'file'), 
                        found = 1; EchoWavePath = pathCandidate; return; %#ok<*NASGU> 
                    end
                end
            end
        end
        % if ~found, fprintf('Telemed EchoWave NOT found!\n'); end
        end % FindTelemedEchoWavePath()
        %%
        function nextFileName = NextAvailableFileName(filename)
            % Find next available filename.
            % Example: 
            % if 'abc.txt', 'abc(1).txt', 'abc(2).txt' exist, 
            % then myNextFileName('abc.txt') will return 'abc(3).txt'
            % W.Chen 06MAR2023
            if ~exist(filename, 'file'), nextFileName = filename; return; end
            [p, f, e] = fileparts(filename); 
            i = 1; upperLimit = 100;
            fn = sprintf('%s(%d)%s', f, i, e);
            nextFileName = fullfile(p, fn);
            while exist(nextFileName, 'file') && i < upperLimit
                i = i+1; fn = sprintf('%s(%d)%s', f, i, e); nextFileName = fullfile(p, fn);  
            end
            if i >=upperLimit, nextFileName = []; end
        end % NextAvailableFileName  
        function [filelist, folder] = mygfl(mask, keepExt)
            % 11MAR2023
            if nargin<2, keepExt = 1; end
            FLs = dir(mask); 
            ix = ~cell2mat({FLs.isdir});
            FLs = FLs(ix);
            filelist = {FLs.name}';
            folder = {FLs.folder}';
            if ~keepExt
                for i = 1:numel(filelist)
                    [p, f, e]=fileparts(filelist{i});
                    filelist{i} = f; 
                end
            end
        end % mygfl
        %%     
    end % methods (Access = public, Static=true)

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = sprintf('%s %s', app.AppName, app.Version);
            initSuccess = true; error_msg = ''; 
            if verLessThan('matlab', app.minMatlabVer)
                initSuccess = false; appSetStatus(app, 'busy');
                error_msg = sprintf('Failed to start. <br> Requires MATLAB %s or newer.', app.minMatlabVerName);
                app.Msg1.HTMLSource = error_msg;  return
            end


            %% Init EchoWave:                       
            if app.Ck_ifRecordTelemed.Value              
                [initSuccess, msg] = DetectEchoWave(app); 
                if ~initSuccess
                    error_msg  = [error_msg '<p>' msg '</p>'];
                end
            else
                app.num_audio_channels = app.user_num_audio_channels;
            end 
            %% Initialize audio device:            
            [success, msg] = selectAudioDevice(app, app.audio_dev_idx);
            if ~success, app.Msg1.HTMLSource = msg; return; end 
            [support, msg] = CheckIfAudioDeviceSettingSupported(app);
            if ~support, app.Msg1.HTMLSource = msg; return; end 
            %% If not success:
            if ~initSuccess
                app.Msg1.HTMLSource = error_msg; 
                app.Bn_Record.Enable = 'off'; app.Bn_Stop.Enable = 'off';
                app.Ck_ifRecordTelemed.Enable = 'on';
                return; 
            end
            %% Success:
            app.Bn_SelectOutputFolder.Text = app.OutputPath;
            app.appStartUpTime = datetime('now');
            app.Msg1.HTMLSource = '<p>Ready</p>';
            app.save_file_prefix = strip(strtrim(app.Ed_FilePrefix.Value), 'right', '_');
            app.audioExt = strip(app.audioExt, 'left', '.');
            app.Bn_Record.Enable = 'on';
            updateFilenameTemplate(app);
            app.Lb_MaxTimeOut.Text = sprintf('Max time out = %s', ...
                string(duration(0, 0, app.maxTimeOut), 'hh:mm:ss'));
            app.Ck_RecordByDurValueChanged(app);
            appSetStatus(app,'ready');
        end

        % Value changed function: Bn_Record
        function StartRecordButtonPushed(app, event)

            if app.isRecording, return; end
            app.isRecording = true;
            app.Bn_Record.Enable = 'off';
            appSetStatus(app, 'busy');
            if app.Ck_ifRecordTelemed.Value
                if ~isEchoWaveConnected(app), app.Msg1.HTMLSource = '<p> Echo Wave disconnected! </p>';  return;   end
            end
            app.Bn_Stop.Enable = 'on';  
            app.recordStartTime = datetime('now');
            app.Msg1.HTMLSource = '<p>Recording...</p>'; 
            GenFilename(app);            
            %% audiorecorder:
            app.audioRecObj= audiorecorder(app.audio_srate, app.audio_bit_rate, app.num_audio_channels, app.audio_dev_idx);
            record(app.audioRecObj);
            %% Telemed Record:
            if app.Ck_ifRecordTelemed.Value
                EchoWaveFreeze(app);
                EchoWaveStartRecord(app); 
            end
            %% Recording timer:
            dur = 0;
            while app.Bn_Record.Value
                currentTime = datetime('now'); durObj = currentTime - app.recordStartTime;
                % s = getaudiodata(app.audioRecObj);
                dur = round(seconds(durObj), 3); 
                app.Ed_TimeRecorded.Value = string(durObj, 'hh:mm:ss.SSS');
                pause(app.timerPeriod);
                if dur > app.maxTimeOut
                    app.Msg1.HTMLSource = '<p>Max time out!</p>';
                    app.isRecording= false; break
                end
                if app.Ck_RecordByDur.Value && dur > app.Ed_RecordDur.Value
                    app.isRecording= false; break
                end
            end % while app.isRecording
            % Stop recording:
            if app.Ck_ifRecordTelemed.Value
                app.asmcmd.ParamSet(app.id_scanning_state_set, app.id_state_b_b1f);  % Freeze
            end
            stop(app.audioRecObj); 
            app.recordStopTime = datetime('now');   
            % Save data:
            if app.Ck_ifRecordTelemed.Value, EchoWaveStopAndSave(app); end
            success = SaveAudio(app);
            if success
                do_log(app);               
            else
                app.Msg1.HTMLSource = '<p>Fail to write audio file! <br>Permission denied. <br> Try another output folder.</p>';
            end            
            %%
            app.Bn_Stop.Enable = 'off'; 
            appSetStatus(app, 'ready');
            %% test
            % app.asmcmd.GoToFrame1n(nFrames, true);
            % img = uint8(app.asmcmd.GetLoadedFrameGray());
        end

        % Button pushed function: Bn_Stop
        function Bn_StopPushed(app, event)
            if ~app.isRecording, return; end
            app.Bn_Stop.Enable = 'off';   app.isRecording = false;
            app.Bn_Record.Value = false;
            app.recordStopTime = datetime('now'); app.Msg1.HTMLSource = '<p>Stopped</p>';     
        end

        % Value changed function: Ck_AppendDate, Ck_AppendTime, 
        % ...and 1 other component
        function Ed_FilePrefixValueChanged(app, event)
            if app.flag_busy, return; end
            updateFilenameTemplate(app)
        end

        % Button pushed function: Bn_SelectOutputFolder
        function Mn_SelectOutputFolderMenuSelected(app, event)
            % UI select output folder:
            if app.flag_busy, return; end
            selpath = uigetdir(app.OutputPath, 'Select output folder');
            figure(app.UIFigure); % Get focus back to main figure
            if isequal(selpath, 0), return; end % user canceled
            app.Bn_SelectOutputFolder.Text = selpath;
            app.OutputPath = selpath;
        end

        % Menu selected function: Mn_SetMaxTimeOut
        function Mn_SetMaxTimeOutMenuSelected(app, event)
            if app.flag_busy, return; end
            defaultAnswer = {sprintf('%d', app.maxTimeOut)};
            answer = inputdlg('Set max time out in seconds (< 36000s [10 hours]):',...
             'Set max time out', [1 100], defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
            isValid = true;
             if isempty(answer) || isnan(str2double(answer{1}))
                 app.Msg1.HTMLSource = 'Invalid max time out!'; return;
             end
            answer = round(str2double(answer{1}));
            if answer < 1 || answer > 36000, isValid = false; end
            if isValid               
                app.maxTimeOut = answer;
                app.Msg1.HTMLSource = '<p>Ready</p>';
                app.Lb_MaxTimeOut.Text = sprintf('Max time out = %s', ...
                    string(duration(0, 0, app.maxTimeOut), 'hh:mm:ss'));
            else
                app.Msg1.HTMLSource = 'Invalid max time out!';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if app.isRecording, return;end
            msg = '';
            msg = {msg, sprintf(' Close %s? ', app.AppName)};
            selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
            switch selection
                case 'OK', 
                    if app.isRecording
                        app.Bn_Record.Value = false;
                        try
                            if app.Ck_ifRecordTelemed.Value
                                EchoWaveFreeze(app);
                            end                    
                            stop(app.audioRecObj);
                        catch
                        end
                    end
                    app.delete(); 
                case 'Cancel', 
                    return
            end            
        end

        % Value changed function: Ck_RecordByDur
        function Ck_RecordByDurValueChanged(app, event)
            value = app.Ck_RecordByDur.Value;
            if app.Ck_RecordByDur.Value
                app.Ed_RecordDur.Enable = 'on';
            else
                app.Ed_RecordDur.Enable = 'off';
            end
        end

        % Menu selected function: Mn_SelectAudioDevice
        function Mn_SelectAudioDeviceSelected(app, event)
            if app.flag_busy, return; end
            [success, msg] = selectAudioDevice(app);
            if ~success, app.Msg1.HTMLSource = msg; return; end 
            [support, msg] = CheckIfAudioDeviceSettingSupported(app);
            if ~support, app.Msg1.HTMLSource = msg; return; end 
            appSetStatus(app, 'ready');
        end

        % Menu selected function: Mn_AudioInfo
        function Mn_AudioInfoMenuSelected(app, event)
            if app.flag_busy, return;end
            title = 'Audio device information';
            if isempty(app.audio_dev_idx)
                msg = 'No audio device.';
            else
                devs = audiodevinfo;  input_devs = devs.input; 
                dev_names = {input_devs.Name}'; device_IDs = cell2mat({input_devs.ID})';   
                ind = find(ismember(device_IDs, app.audio_dev_idx));
                dev_name = dev_names{ind};
                msg = sprintf(...
                ['Audio Device ID: %d\n' ...
                'Audio Device Name:\n%s\n' ...
                'Sampling Rate (Hz): %d\n' ...
                'bit rate: %d\n' ...
                'Number of channels: %d\n'], ...
                app.audio_dev_idx, dev_name, app.audio_srate, app.audio_bit_rate, app.num_audio_channels);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info');             
        end

        % Menu selected function: Mn_About
        function Mn_AboutMenuSelected(app, event)
            if app.flag_busy, return;end
            title = '';
            sinceYear = 2023;
            currentYear = year(datetime);
            cInfo = sprintf('Copyright (C) %04d-%04d Wei-Rong Chen', sinceYear, currentYear); 
            email = '<wei-rong.chen[AT]yale.edu>';
            licInfo = 'This program is free software under GNU General Public License, version 3. This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. See the GNU General Public License for more details.';
            msg = sprintf('%s\nVersion: %s\nLatest Update: %s \n\n %s \n %s\n %s', ...
            app.AppName, app.Version, app.Update, cInfo, email, licInfo);                
            uialert(app.UIFigure, msg, title, 'icon', 'info');               
        end

        % Value changed function: Ck_ifRecordTelemed
        function Ck_ifRecordTelemedValueChanged(app, event)
            if app.isRecording, return;end 
            app.startupFcn;            
        end

        % Menu selected function: Mn_AudioSettings
        function Mn_AudioSettingsMenuSelected(app, event)

            defaultAnswer = {sprintf('%d', app.audio_srate), sprintf('%d', app.user_num_audio_channels)};
            questions = {'Audio sampling rate (Hz)', 'Number of audio channels'};
            answers = inputdlg(questions,...
             '',1, defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
            if isempty(answers), return; end % user cancelled
            app.Msg1.HTMLSource = 'Ready.';
            %% Samping rate:
            ans1 = round(str2double(answers{1}));
            if ~isnan(ans1) && ans1 >= 16000 && ans1 <= 96000
                app.audio_srate = ans1;
                app.Msg1.HTMLSource = ...
                   sprintf('%s <br> Set sampling rate to %d Hz.', app.Msg1.HTMLSource, ans1);
            else
                 app.Msg1.HTMLSource = ...
                   sprintf('%s <br> Unsupported sampling rate.', app.Msg1.HTMLSource, ans1);   
            end %
            %% Number of audio channels:
            ans2 = round(str2double(answers{2}));
            if ~isnan(ans2) && ans2 > 0         
                app.user_num_audio_channels = ans2;
                if ~app.Ck_ifRecordTelemed.Value 
                    app.num_audio_channels = app.user_num_audio_channels;
                    app.Msg1.HTMLSource = ...
                       sprintf('%s <br> Set number of audio channgels to %d.', app.Msg1.HTMLSource, ans2);
                end
            else
                 app.Msg1.HTMLSource = ...
                   sprintf('%s <br> Unsupported number of channels.', app.Msg1.HTMLSource);
            end
            [support, msg] = CheckIfAudioDeviceSettingSupported(app);
            if ~support, app.Msg1.HTMLSource = msg; return; end 
            appSetStatus(app, 'ready');  
        end

        % Menu selected function: Mn_ConvertTVDtoMP4
        function Mn_ConvertTVDtoMP4MenuSelected(app, event)
            if app.flag_busy, return; end
            if ~isEchoWaveConnected(app), app.Msg1.HTMLSource = 'EchoWave disconnected!';return;end
            mask = sprintf('%s%s*.tvd', app.OutputPath, filesep);
            fl = app.mygfl(mask,1);
            if isempty(fl), app.Msg1.HTMLSource = 'No TVD files!';return;end
            msg = sprintf('%d TVD files found.', numel(fl));
            msg = {msg, 'Video conversion takes very long time.', 'Continue?'};
            selection = uiconfirm(app.UIFigure, msg,'Confirm conversion', 'Icon','warning');
            switch selection
                case 'OK', ; case 'Cancel', return
            end   
            appSetStatus(app, 'busy');
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Converting TVD to MP4','Indeterminate','on');
            ConvertTVDtoMP4(app);
            close(UIprogressDialog_handle);
            appSetStatus(app, 'ready');
        end

        % Menu selected function: LoadEchoWavePresetMenu
        function LoadEchoWavePresetMenuSelected(app, event)
            if app.flag_busy, return; end
            if ~app.Ck_ifRecordTelemed.Value, return; end
            appSetStatus(app, 'busy');
            if ~isEchoWaveConnected(app), app.Msg1.HTMLSource = 'EchoWave disconnected!';return;end
                EchoWaveFreeze(app);
            beamformer = 'MCE'; probe = 'MC4-2R20S-3';
            preset_name = 'Haskins Tongue';

            filterspec = {'*.dat','Preset .dat file'};
            [fn, fpath] = uigetfile(filterspec, 'Select preset file');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  appSetStatus(app, 'ready');return; end       
            preset_FullFName = fullfile(fpath, fn);
            cmd = sprintf('%s -import_presets %s "%s" %s "%s"', ...
                app.EchoWaveCmd, beamformer, preset_FullFName, probe, preset_name);
            [status,cmdout] = system(cmd, '-echo'); %#ok<*ASGLU> 
            cmd = sprintf('%s -apply_preset "%s"', ...
                app.EchoWaveCmd, preset_name);
            [status,cmdout] = system(cmd, '-echo'); %#ok<*ASGLU> 
            EchoWaveFreeze(app);
            appSetStatus(app, 'ready');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Mn_Setting = uimenu(app.UIFigure);
            app.Mn_Setting.Text = 'Setting';
            app.Mn_SetMaxTimeOut = uimenu(app.Mn_Setting);
            app.Mn_SetMaxTimeOut.MenuSelectedFcn = createCallbackFcn(app, @Mn_SetMaxTimeOutMenuSelected, true);
            app.Mn_SetMaxTimeOut.Text = 'Set max time out';
            app.Mn_SelectAudioDevice = uimenu(app.Mn_Setting);
            app.Mn_SelectAudioDevice.MenuSelectedFcn = createCallbackFcn(app, @Mn_SelectAudioDeviceSelected, true);
            app.Mn_SelectAudioDevice.Text = 'Select audio device';
            app.Mn_AudioSettings = uimenu(app.Mn_Setting);
            app.Mn_AudioSettings.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioSettingsMenuSelected, true);
            app.Mn_AudioSettings.Text = 'Audio settings';
            app.Mn_Info = uimenu(app.UIFigure);
            app.Mn_Info.Text = 'Info';
            app.Mn_AudioInfo = uimenu(app.Mn_Info);
            app.Mn_AudioInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioInfoMenuSelected, true);
            app.Mn_AudioInfo.Text = 'Audio Info';
            app.Mn_About = uimenu(app.Mn_Info);
            app.Mn_About.MenuSelectedFcn = createCallbackFcn(app, @Mn_AboutMenuSelected, true);
            app.Mn_About.Text = 'About MewRecorder';
            app.Mn_EchoWaveTools = uimenu(app.UIFigure);
            app.Mn_EchoWaveTools.Text = 'EchoWaveTools';
            app.Mn_ConvertTVDtoMP4 = uimenu(app.Mn_EchoWaveTools);
            app.Mn_ConvertTVDtoMP4.MenuSelectedFcn = createCallbackFcn(app, @Mn_ConvertTVDtoMP4MenuSelected, true);
            app.Mn_ConvertTVDtoMP4.Text = 'Convert All TVD to MP4';
            app.LoadEchoWavePresetMenu = uimenu(app.Mn_EchoWaveTools);
            app.LoadEchoWavePresetMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadEchoWavePresetMenuSelected, true);
            app.LoadEchoWavePresetMenu.Text = 'Load EchoWave Preset';
            app.Pn_Filename = uipanel(app.UIFigure);
            app.FilenameprefixLabel = uilabel(app.Pn_Filename);
            app.FilenameprefixLabel.Text = 'Filename:   prefix=';
            app.Ed_FilePrefix = uieditfield(app.Pn_Filename, 'text');
            app.Ed_FilePrefix.ValueChangedFcn = createCallbackFcn(app, @Ed_FilePrefixValueChanged, true);
            app.Ed_FilePrefix.Value = 'recording';
            app.Ck_AppendDate = uicheckbox(app.Pn_Filename);
            app.Ck_AppendDate.ValueChangedFcn = createCallbackFcn(app, @Ed_FilePrefixValueChanged, true);
            app.Ck_AppendDate.Tooltip = {'Append date to filename.'};
            app.Ck_AppendDate.Text = 'append date';
            app.Ck_AppendDate.Value = true;
            app.Ck_AppendTime = uicheckbox(app.Pn_Filename);
            app.Ck_AppendTime.ValueChangedFcn = createCallbackFcn(app, @Ed_FilePrefixValueChanged, true);
            app.Ck_AppendTime.Tooltip = {'Append recording started time stamp to filename.'};
            app.Ck_AppendTime.Text = 'append time';
            app.Ck_AppendTime.Value = true;
            app.FilenameLabel = uilabel(app.Pn_Filename);
            app.FilenameLabel.Text = 'Filename=';
            app.Ed_Filename = uieditfield(app.Pn_Filename, 'text');
            app.Ed_Filename.Editable = 'off';
            app.Ed_Filename.Enable = 'off';
            app.Bn_Stop = uibutton(app.UIFigure, 'push');
            app.Bn_Stop.ButtonPushedFcn = createCallbackFcn(app, @Bn_StopPushed, true);
            app.Bn_Stop.Enable = 'off';
            app.Bn_Stop.Text = 'Stop';
            app.Pn_MsgBox = uipanel(app.UIFigure);
            app.Msg1 = uihtml(app.Pn_MsgBox);
            app.TimeRecordedLabel = uilabel(app.UIFigure);
            app.TimeRecordedLabel.Text = 'Time Recorded:';
            app.Ed_TimeRecorded = uieditfield(app.UIFigure, 'text');
            app.Ed_TimeRecorded.Editable = 'off';
            app.Ed_TimeRecorded.Value = '00:00:00.000';
            app.Ck_ifNormAudio = uicheckbox(app.UIFigure);
            app.Ck_ifNormAudio.Text = 'Normalize audio amplitude';
            app.Ck_ifNormAudio.Value = true;
            app.Bn_SelectOutputFolder = uibutton(app.UIFigure, 'push');
            app.Bn_SelectOutputFolder.ButtonPushedFcn = createCallbackFcn(app, @Mn_SelectOutputFolderMenuSelected, true);
            app.Bn_SelectOutputFolder.Tooltip = {'Click to select folder.'};
            app.Bn_SelectOutputFolder.Text = 'output folder';
            app.OutputfolderLabel = uilabel(app.UIFigure);
            app.OutputfolderLabel.Text = 'Output folder:';
            app.Lb_MaxTimeOut = uilabel(app.UIFigure);
            app.Lb_MaxTimeOut.Text = 'Max time out = 00:00:00';
            app.Ck_RecordByDur = uicheckbox(app.UIFigure);
            app.Ck_RecordByDur.ValueChangedFcn = createCallbackFcn(app, @Ck_RecordByDurValueChanged, true);
            app.Ck_RecordByDur.Text = 'Record by duration (sec.): ';
            app.Ed_RecordDur = uieditfield(app.UIFigure, 'numeric');
            app.Ed_RecordDur.Limits = [0.5 7200];
            app.Ed_RecordDur.ValueDisplayFormat = '%11.1f';
            app.Ed_RecordDur.Value = 5;
            app.Ck_ifRecordTelemed = uicheckbox(app.UIFigure);
            app.Ck_ifRecordTelemed.ValueChangedFcn = createCallbackFcn(app, @Ck_ifRecordTelemedValueChanged, true);
            app.Ck_ifRecordTelemed.Tooltip = {'Record Telemed ultrasound video via EchoWave'};
            app.Ck_ifRecordTelemed.Text = 'Record Telemed Ultrasound';
            app.Lb_AudioSettings = uilabel(app.UIFigure);
            app.Lb_AudioSettings.Text = 'Num audio channels = 2';
            app.Bn_Record = uibutton(app.UIFigure, 'state');
            app.Bn_Record.ValueChangedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.Bn_Record.Text = 'Start Record';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MewRecorder

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end